classdef hazegenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HazeGeneratorUIFigure          matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        FolderSelectToggle             matlab.ui.container.toolbar.PushTool
        PlayVideoToggle                matlab.ui.container.toolbar.ToggleTool
        AdvancedModeToggle             matlab.ui.container.toolbar.ToggleTool
        ToggleTool                     matlab.ui.container.toolbar.ToggleTool
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SourcesTab                     matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        DepthMapLabel                  matlab.ui.control.Label
        DepthMapType                   matlab.ui.control.DropDown
        SkyMaskLabel                   matlab.ui.control.Label
        SkyMask                        matlab.ui.control.DropDown
        HazeGenerationTab              matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        AirlightButton                 matlab.ui.control.Button
        AirlightColourLabel            matlab.ui.control.Label
        VisibleDistanceEditFieldLabel  matlab.ui.control.Label
        VisibleDistanceEditField       matlab.ui.control.NumericEditField
        DoubleMaskButton               matlab.ui.control.StateButton
        DebugButton                    matlab.ui.control.StateButton
        PreviewTab                     matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        PreviewSequenceDropDownLabel   matlab.ui.control.Label
        PreviewSequenceDropDown        matlab.ui.control.DropDown
        PreviewTypeLabel               matlab.ui.control.Label
        PreviewType                    matlab.ui.container.ButtonGroup
        PreviewTypeOutput              matlab.ui.control.ToggleButton
        PreviewTypeDepthMap            matlab.ui.control.ToggleButton
        PreviewTypeTransmission        matlab.ui.control.ToggleButton
        PreviewTypeInput               matlab.ui.control.ToggleButton
        PreviewFrameSpinnerLabel       matlab.ui.control.Label
        PreviewFrameSpinner            matlab.ui.control.Spinner
        GridLayout3                    matlab.ui.container.GridLayout
        PreviewImage                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        kittiPath                       % Path to KITTI Files
        previewPath                     % Path to current preview folder
%         previewBuildRequired            % Whether a preview requires construction
%         builtPreview                    % A constructed preview
        sequencePaths                   % A map containing sequence paths
    end
    
    methods (Access = private)
        
        function generateSingular(app, image)
            
        end
        
        function t = createTransmission(app, depthMap, framePath)
            if app.DebugButton.Value
                imgPath = fullfile(app.previewPath,"image_02","data", framePath+".png");
                img = imread(imgPath);
                img = rgb2gray(im2double(img));
                se = strel('square',5);
                depthMap = imdilate(depthMap,se);
                depthMap = imguidedfilter(depthMap, img, 'NeighborhoodSize',[5 5]);
            end
            depthMap = depthMap./1000;
            beta = 3.912/app.VisibleDistanceEditField.Value;
            beta = repelem(beta,3);
            beta = reshape(beta,[1 1 3]);
            t = exp(-beta.*depthMap);
            if nargin>2 && app.DoubleMaskButton.Value
                maskPath = fullfile(app.previewPath,"sky_mask",app.SkyMask.Value,"img",framePath+".png");
                skyMask = imread(maskPath);
                skyMask = 1.0 - im2double(skyMask);
                t = t.*skyMask;
            end
            
            if app.DebugButton.Value
%                 se = strel('square',5);
%                 t = imerode(t,se);
%                 imgPath = fullfile(app.previewPath,"image_02","data", framePath+".png");
%                 img = imread(imgPath);
%                 img = rgb2gray(im2double(img));
%                 t = imguidedfilter(t, img, 'NeighborhoodSize',[5 5]);
            end
            
            assignin('base','t',t);
        end
        
        function preview = createOutputPreview(app, inp, t)
            A = reshape(app.AirlightButton.BackgroundColor, [1 1 3]);
            preview = im2uint8(inp.*t+A.*(1.0-t));
        end

        
        function disparity = createMaskedDisparity(app, depthPath, framePath)
            depthMap = load(depthPath).depth;
            if app.DepthMapType.Value == "leastereo"
                depthMap = depthMap(10:end,7:end);
            end
            disparity = 1.0./depthMap;
               
            maskPath = fullfile(app.previewPath,"sky_mask",app.SkyMask.Value,"img",framePath+".png");
            skyMask = imread(maskPath);
            skyMask = 1.0 - im2double(skyMask);
            disparity = skyMask.*disparity;
        end
        
        function preview = createMaskedDepthPreview(app, depthPath, framePath)
            disp = app.createMaskedDisparity(depthPath, framePath);
            preview = magmacolormap(1.0-disp);
        end
        
        function selectKittiPath(app)
            tempPath = uigetdir(app.kittiPath,'Select the location of the KITTI "train" folder');
            drawnow;
            figure(app.HazeGeneratorUIFigure);
%             disp(app.kittiPath)
            if tempPath~=0
                app.kittiPath = tempPath;
                app.updateKittiPath();
            end
        end
        
        function updateKittiPath(app)
            folders = dir(app.kittiPath);
            app.sequencePaths = containers.Map('KeyType','char','ValueType','char');
            for i = 3:length(folders)
                folderName = folders(i).name;
                sequence = dir(fullfile(app.kittiPath,folderName));
                sequence = sequence([sequence.isdir]);
            %     names = names(3:end);
                for j = 3:length(sequence)
                    app.sequencePaths(sequence(j).name)=fullfile(sequence(j).folder,sequence(j).name);
                end
            end
            app.PreviewSequenceDropDown.Items = app.sequencePaths.keys;
%             assignin('base','testNames',names);
%             assignin('base','test',app.PreviewSequenceDropDown.Items);
            app.updatePreviewFolder();
        end
        
        function updatePreviewFolder(app)
            %disp(app.PreviewSequenceDropDown.Value);
            %disp(app.sequencePaths(app.PreviewSequenceDropDown.Value));
            app.previewPath = fullfile(app.sequencePaths(app.PreviewSequenceDropDown.Value));
            numImg = numel(dir(fullfile(app.previewPath,"image_02","data",'*.png')));
            app.PreviewFrameSpinner.Value = 0;
            app.PreviewFrameSpinner.Limits = [-1 numImg];
            app.updatePreview();
        end
        
%         function constructPreview(app)
%             depthPath = fullfile("depth","unguided",app.DepthMapType.Value,"mat");
%             framePath = [num2str(app.PreviewFrameSpinner.Value,'%010.f') '.png'];
%         end

        function updatePreview(app)
            framePath = num2str(app.PreviewFrameSpinner.Value,'%010.f');
            switch app.PreviewType.SelectedObject
                case app.PreviewTypeOutput
                    inp = imread(fullfile(app.previewPath,"image_02","data", framePath+".png"));
                    inp = im2double(inp);
                    depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                    if app.SkyMask.Value == "none"
                        t = app.createTransmission(load(depthPath).depth);
                    else
                        disp = app.createMaskedDisparity(depthPath,framePath);
                        depth = 1.0./disp;
                        t = app.createTransmission(depth, framePath);
                    end
                    app.PreviewImage.ImageSource = app.createOutputPreview(inp,t);
                    
                case app.PreviewTypeDepthMap
                    if app.SkyMask.Value ~= "none"
                        depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                        app.PreviewImage.ImageSource = app.createMaskedDepthPreview(depthPath,framePath);
                    else
                        app.PreviewImage.ImageSource = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"img", framePath+".png");

                        
                    end
                    
                case app.PreviewTypeTransmission
                    depthPath = fullfile(app.previewPath,"depth",app.DepthMapType.Value,"mat", framePath+".mat");
                    if app.SkyMask.Value == "none"
                        app.PreviewImage.ImageSource = im2uint8(app.createTransmission(load(depthPath).depth));
                    else
                        
                        disp = app.createMaskedDisparity(depthPath,framePath);
                        depth = 1.0./disp;
                        app.PreviewImage.ImageSource = im2uint8(app.createTransmission(depth,framePath));
                    end
                    
                otherwise
                    app.PreviewImage.ImageSource = fullfile(app.previewPath,"image_02","data", framePath+".png");
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.kittiPath = fullfile(fileparts(mfilename('fullpath')),'../KITTI/train');
            app.updateKittiPath();
%             disp(app.kittiPath)
            app.HazeGeneratorUIFigure.Visible = 'off';
            if ~isfolder(app.kittiPath)
                app.changeKittiPath();
            end
            app.HazeGeneratorUIFigure.Visible = 'on';
            
        end

        % Clicked callback: FolderSelectToggle
        function FolderSelectToggleClicked(app, event)
            app.selectKittiPath();
        end

        % Value changed function: PreviewSequenceDropDown
        function PreviewSequenceDropDownValueChanged(app, event)
            app.updatePreviewFolder();
        end

        % Selection changed function: PreviewType
        function PreviewTypeSelectionChanged(app, event)
            app.updatePreview();
        end

        % Value changing function: PreviewFrameSpinner
        function PreviewFrameSpinnerValueChanging(app, event)
            changingValue = event.Value;
%             disp(changingValue)
            if changingValue==-1
                app.PreviewFrameSpinner.Value = app.PreviewFrameSpinner.Limits(2)-1;
%                 disp(app.PreviewFrameSpinner.Value)
            elseif changingValue==app.PreviewFrameSpinner.Limits(2)
                app.PreviewFrameSpinner.Value = 0;
            end
        end

        % Value changed function: PreviewFrameSpinner
        function PreviewFrameSpinnerValueChanged(app, event)
            changingValue = event.Value;
%             disp(changingValue)
            if changingValue==-1
                app.PreviewFrameSpinner.Value = app.PreviewFrameSpinner.Limits(2)-1;
%                 disp(app.PreviewFrameSpinner.Value)
            elseif changingValue==app.PreviewFrameSpinner.Limits(2)
                app.PreviewFrameSpinner.Value = 0;
            end
            app.updatePreview()
        end

        % Value changed function: DepthMapType
        function DepthMapTypeValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: SkyMask
        function SkyMaskValueChanged(app, event)
            app.updatePreview();
        end

        % Button pushed function: AirlightButton
        function AirlightButtonPushed(app, event)
           c = uisetcolor(app.AirlightButton.BackgroundColor);
           drawnow;
           figure(app.HazeGeneratorUIFigure);
           app.AirlightButton.BackgroundColor = c;
           app.updatePreview();
        end

        % Value changed function: VisibleDistanceEditField
        function VisibleDistanceEditFieldValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: DoubleMaskButton
        function DoubleMaskButtonValueChanged(app, event)
            app.updatePreview();
        end

        % Value changed function: DebugButton
        function DebugButtonValueChanged(app, event)
            app.updatePreview();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HazeGeneratorUIFigure = uifigure('Visible', 'off');
            app.HazeGeneratorUIFigure.Name = 'Haze Generator';
            app.Toolbar = uitoolbar(app.HazeGeneratorUIFigure);
            app.FolderSelectToggle = uipushtool(app.Toolbar);
            app.FolderSelectToggle.Tooltip = {'Select KITTI path'};
            app.FolderSelectToggle.ClickedCallback = createCallbackFcn(app, @FolderSelectToggleClicked, true);
            app.PlayVideoToggle = uitoggletool(app.Toolbar);
            app.PlayVideoToggle.Tooltip = {'Toggle Preview Video'};
            app.PlayVideoToggle.Separator = 'on';
            app.AdvancedModeToggle = uitoggletool(app.Toolbar);
            app.AdvancedModeToggle.Tooltip = {'Toggle Advanced Mode'};
            app.AdvancedModeToggle.Separator = 'on';
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Separator = 'on';
            app.GridLayout = uigridlayout(app.HazeGeneratorUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SourcesTab = uitab(app.TabGroup);
            app.SourcesTab.Title = 'Sources';
            app.GridLayout4 = uigridlayout(app.SourcesTab);
            app.DepthMapLabel = uilabel(app.GridLayout4);
            app.DepthMapLabel.Text = 'Depth Map';
            app.DepthMapType = uidropdown(app.GridLayout4);
            app.DepthMapType.Items = {'MonoDepth2', 'ManyDepth', 'LEAStereo'};
            app.DepthMapType.ItemsData = {'unguided/monodepth', 'unguided/manydepth', 'leastereo'};
            app.DepthMapType.ValueChangedFcn = createCallbackFcn(app, @DepthMapTypeValueChanged, true);
            app.DepthMapType.Value = 'unguided/manydepth';
            app.SkyMaskLabel = uilabel(app.GridLayout4);
            app.SkyMaskLabel.Text = 'Sky Mask';
            app.SkyMask = uidropdown(app.GridLayout4);
            app.SkyMask.Items = {'Standard', 'None'};
            app.SkyMask.ItemsData = {'default_refined', 'none', ''};
            app.SkyMask.ValueChangedFcn = createCallbackFcn(app, @SkyMaskValueChanged, true);
            app.SkyMask.Value = 'default_refined';
            app.HazeGenerationTab = uitab(app.TabGroup);
            app.HazeGenerationTab.Title = 'Haze Generation';
            app.GridLayout5 = uigridlayout(app.HazeGenerationTab);
            app.AirlightButton = uibutton(app.GridLayout5, 'push');
            app.AirlightButton.ButtonPushedFcn = createCallbackFcn(app, @AirlightButtonPushed, true);
            app.AirlightButton.Text = '';
            app.AirlightColourLabel = uilabel(app.GridLayout5);
            app.AirlightColourLabel.Text = 'Airlight Colour';
            app.VisibleDistanceEditFieldLabel = uilabel(app.GridLayout5);
            app.VisibleDistanceEditFieldLabel.Text = 'Visible Distance';
            app.VisibleDistanceEditField = uieditfield(app.GridLayout5, 'numeric');
            app.VisibleDistanceEditField.Limits = [0.0001 1];
            app.VisibleDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @VisibleDistanceEditFieldValueChanged, true);
            app.VisibleDistanceEditField.Value = 0.4;
            app.DoubleMaskButton = uibutton(app.GridLayout5, 'state');
            app.DoubleMaskButton.ValueChangedFcn = createCallbackFcn(app, @DoubleMaskButtonValueChanged, true);
            app.DoubleMaskButton.Text = 'Double Mask';
            app.DoubleMaskButton.Value = true;
            app.DebugButton = uibutton(app.GridLayout5, 'state');
            app.DebugButton.ValueChangedFcn = createCallbackFcn(app, @DebugButtonValueChanged, true);
            app.DebugButton.Text = 'Debug';
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.GridLayout2 = uigridlayout(app.PreviewTab);
            app.PreviewSequenceDropDownLabel = uilabel(app.GridLayout2);
            app.PreviewSequenceDropDownLabel.Text = 'Preview Sequence';
            app.PreviewSequenceDropDown = uidropdown(app.GridLayout2);
            app.PreviewSequenceDropDown.Items = {};
            app.PreviewSequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewSequenceDropDownValueChanged, true);
            app.PreviewSequenceDropDown.Value = {};
            app.PreviewTypeLabel = uilabel(app.GridLayout2);
            app.PreviewTypeLabel.Text = 'Preview Type';
            app.PreviewType = uibuttongroup(app.GridLayout2);
            app.PreviewType.SelectionChangedFcn = createCallbackFcn(app, @PreviewTypeSelectionChanged, true);
            app.PreviewTypeOutput = uitogglebutton(app.PreviewType);
            app.PreviewTypeOutput.Text = 'Output';
            app.PreviewTypeDepthMap = uitogglebutton(app.PreviewType);
            app.PreviewTypeDepthMap.Text = 'Depth Map';
            app.PreviewTypeTransmission = uitogglebutton(app.PreviewType);
            app.PreviewTypeTransmission.Text = 'Transmission';
            app.PreviewTypeInput = uitogglebutton(app.PreviewType);
            app.PreviewTypeInput.Text = 'Input';
            app.PreviewTypeInput.Value = true;
            app.PreviewFrameSpinnerLabel = uilabel(app.GridLayout2);
            app.PreviewFrameSpinnerLabel.Text = 'Preview Frame';
            app.PreviewFrameSpinner = uispinner(app.GridLayout2);
            app.PreviewFrameSpinner.ValueChangingFcn = createCallbackFcn(app, @PreviewFrameSpinnerValueChanging, true);
            app.PreviewFrameSpinner.Limits = [-1 100];
            app.PreviewFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @PreviewFrameSpinnerValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PreviewImage = uiimage(app.GridLayout3);
            % Show the figure after all components are created
            app.HazeGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hazegenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HazeGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HazeGeneratorUIFigure)
        end
    end
end